classdef angle_contest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RichPeopleVersionPanel      matlab.ui.container.Panel
        Storm10TimesButton          matlab.ui.control.Button
        ParticleSizeEditField       matlab.ui.control.NumericEditField
        ParticleSizeEditFieldLabel  matlab.ui.control.Label
        ParticleStormButton         matlab.ui.control.Button
        BasicGame4PoorsPanel        matlab.ui.container.Panel
        LevelSelector8EditField     matlab.ui.control.NumericEditField
        LevelSelector15Label        matlab.ui.control.Label
        MovePointsButton            matlab.ui.control.Button
        DropPointsButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        X_locations = []; % Description
        Y_locations = []; % Description
        Z_locations = [];
        X_points = [] % Description
        Y_points = []% Description
        Z_points = [] % Description
        xmin = [];
        xmax = [];
        ymin = []
        ymax = [];
        R = 100; % Description
        N = 0;% Description
    end
    
    methods (Access = private)
        
        function cost = cross_calc2(~, variables)

            data = load("scenario_info.mat");
            info = data.scenario_info;
    
            % Set fixed RX locations
            num_locs = length(info.X_locations);
        
            % Unload variables vector into locations
            num_vars = length(variables);
            x_points = variables(1:(num_vars/2));
            y_points = variables((num_vars/2)+1:end);
            num_points = length(x_points);
            
            % Compute Score A
            count = 0;
            lines = zeros(num_points * num_locs, 6);
            for i = 1:length(x_points)
                for j = 1:length(info.X_locations)
                    count = count+1;
                    lines(count,:) = [x_points(i),y_points(i),0,info.X_locations(j),info.Y_locations(j),0];
                end
            end
            
            P_final = nan * zeros(count * count, 3);
            count2 = 0;
            for i = 1:count
                for j = 1:count
                    if i ~= j
                        PA = [lines(i,(1:3));lines(j,(1:3))];
                        PB = [lines(i,(4:6));lines(j,(4:6))];
        
                        if (PA(1,1) ~= PA(2,1) || PA(1,2) ~= PA(2,2)) && (PB(1,1) ~= PB(2,1) || PB(1,2) ~= PB(2,2))
                            [P_int] = lineIntersect3D(PA,PB);
        
                            angle_line1 = atan2d((PA(1,2)-PB(1,2)),(PA(1,1)-PB(1,1)));
                            angle_line2 = atan2d((PA(2,2)-PB(2,2)),(PA(2,1)-PB(2,1)));
        
                            angle_point1 = atan2d((P_int(1,2)-PB(1,2)),(P_int(1,1)-PB(1,1)));
                            angle_point2 = atan2d((P_int(1,2)-PB(2,2)),(P_int(1,1)-PB(2,1)));
        
        
                            angle_delta1 = abs(angle_line1-angle_point1);
                            angle_delta2 = abs(angle_line2-angle_point2);
        
                            if angle_delta1<10 && angle_delta2 < 10
                                X_same = x_points - P_int(1);
                                Y_same = y_points - P_int(2);
                                X_same = abs(X_same) < .001;
                                Y_same = abs(Y_same) < .001;
                                Same1 = X_same & Y_same;
        
                                X_same2 = info.X_locations - P_int(1);
                                Y_same2 = info.Y_locations - P_int(2);
                                X_same2 = abs(X_same2) < .001;
                                Y_same2 = abs(Y_same2) < .001;
                                Same2 = X_same2 & Y_same2;
        
                                if P_int(1) >= info.xmin && P_int(1) <= info.xmax && P_int(2) >= info.ymin && P_int(2) <= info.ymax && sum(Same1) == 0 && sum(Same2)==0
                                    count2 = count2+1;
                                    P_final(count2,:) = P_int(1,:);
                                end
                            end
                        end
                    end
                end
            end
        
            idx_keep = ~isnan(P_final(:, 1));
            P_final = P_final(idx_keep, :);
            
            
            % Compute Score B
            x_bins = info.xmin:.2:info.xmax+.2;
            y_bins = info.ymin:.2:info.ymax+.2;
            num_xbins = length(x_bins);
            num_ybins = length(y_bins);
            
            x_take = zeros(num_xbins - 1, 1);
            y_take = zeros(num_ybins - 1, 1);
            C_contour = zeros(num_xbins - 1, num_ybins - 1);
            
            for i = 1:num_xbins - 1
                x_take(i) = (x_bins(i)+x_bins(i+1))/2;
                x_take(i) = x_bins(i);
                indx1 = P_final(:,1) >= x_bins(i) & P_final(:,1) < x_bins(i+1);
        
                for j = 1:num_ybins - 1
                    y_take(j) = (y_bins(j)+y_bins(j+1))/2;
                    y_take(j) = y_bins(j);
                    indx2 = P_final(:,2) >= y_bins(j) & P_final(:,2) < y_bins(j+1);
        
                    indx_total = indx1 & indx2;
                    C_contour(i,j) = sum(indx_total);
                end
            end
            
            C_contour(C_contour>=20) = 20;
            
            
            [A,B] = size(C_contour);
            C_Area = (A-1)*(B-1);
            
            indx_good = C_contour(C_contour>=1);
            num_good = length(indx_good);
        
            % Final score
            score = (count2/2)*((num_good)/C_Area);
            % if isnan(score)
            %     save('debug.mat', "count2", "num_good", "C_Area", "C_contour", "x_bins", 'y_bins');
            % end
            cost = -score;

        end
        
        function info = get_scenario_info(~, scenario)
            switch scenario
                case 1
                    info.X_locations = [0,0,0,0,0,0,0,0,0,0,0];
                    info.Y_locations = [0,1,2,3,4,5,6,7,8,9,10];
                    info.Z_locations = [0,0,0,0,0,0,0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 5;
                case 2
                    info.X_locations = [0,0,0,0,0];
                    info.Y_locations = [1,3,5,7,9];
                    info.Z_locations = [0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 5;
                case 3
                    info.X_locations = [0,0,5,10,10];
                    info.Y_locations = [0,10,5,0,10];
                    info.Z_locations = [0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 5;
                case 4
                    info.X_locations = [0,1,0,1,0,1,0,1,0,1,0,4,3];
                    info.Y_locations = [0,1,2,3,4,5,6,7,8,9,10,4,6];
                    info.Z_locations = [0,0,0,0,0,0,0,0,0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 10;

                case 5
                    info.X_locations = [0,1,0,1,0,1,0,1,0,1, 0,4,3,6,7,8,7,6,8,7];
                    info.Y_locations = [0,1,2,3,4,5,6,7,8,9,10,4,6,1,2,1,1,2,3,4];
                    info.Z_locations = [0,0,0,0,0,0,0,0,0,0, 0,0,0,0,0,0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 10;

                case 8
                    info.X_locations = [0, 0,5,4,3,2,3,4,9,7];
                    info.Y_locations = 10-[0,10,8,1,2,3,6,7,2,4];
                    info.Z_locations = [0, 0,0,0,0,0,0,0,0,0];
                    info.X_points = [];
                    info.Y_points = [];
                    info.Z_points = [];
                    info.xmin = 0;
                    info.xmax = 10;
                    info.ymin = 0;
                    info.ymax = 10;
                    info.N = 6;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DropPointsButton
        function DropPointsButtonPushed(app, event)
            if app.LevelSelector8EditField.Value == 1
                app.X_locations = [0,0,0,0,0,0,0,0,0,0,0];
                app.Y_locations = [0,1,2,3,4,5,6,7,8,9,10];
                app.Z_locations = [0,0,0,0,0,0,0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 5;
            elseif app.LevelSelector8EditField.Value == 2
                app.X_locations = [0,0,0,0,0];
                app.Y_locations = [1,3,5,7,9];
                app.Z_locations = [0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 5;
            elseif app.LevelSelector8EditField.Value == 3
                app.X_locations = [0,0,5,10,10];
                app.Y_locations = [0,10,5,0,10];
                app.Z_locations = [0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 5;
            elseif app.LevelSelector8EditField.Value == 4
                app.X_locations = [0,1,0,1,0,1,0,1,0,1,0,4,3];
                app.Y_locations = [0,1,2,3,4,5,6,7,8,9,10,4,6];
                app.Z_locations = [0,0,0,0,0,0,0,0,0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 10;
            elseif app.LevelSelector8EditField.Value == 5
                app.X_locations = [0,1,0,1,0,1,0,1,0,1, 0,4,3,6,7,8,7,6,8,7];
                app.Y_locations = [0,1,2,3,4,5,6,7,8,9,10,4,6,1,2,1,1,2,3,4];
                app.Z_locations = [0,0,0,0,0,0,0,0,0,0, 0,0,0,0,0,0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 10;
            elseif app.LevelSelector8EditField.Value == 8
                app.X_locations = [0, 0,5,4,3,2,3,4,9,7];
                app.Y_locations = 10-[0,10,8,1,2,3,6,7,2,4];
                app.Z_locations = [0, 0,0,0,0,0,0,0,0,0];
                app.X_points = [];
                app.Y_points = [];
                app.Z_points = [];
                app.xmin = 0;
                app.xmax = 10;
                app.ymin = 0;
                app.ymax = 10;
                app.N = 6;
            end

            h=figure;
            hold on
            grid on
            ax=gca;
            ax.Color = [.5,.5,.5];
            h.Position = [0,0,1500,1500];
            scatter(app.X_locations,app.Y_locations,'MarkerFaceColor','r','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','d')
            line([app.xmin,app.xmin],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmax,app.xmax],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymin,app.ymin],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymax,app.ymax],'linewidth',2,'color','k')
            xlim([app.xmin-1,app.xmax+1])
            ylim([app.ymin-1,app.ymax+1])

            if isempty(app.X_points)
                [x,y] = ginput(app.N);
                valid_points = x >= app.xmin & x <= app.xmax & y >= app.ymin & y <= app.ymax;
                app.X_points = x(valid_points);
                app.Y_points = y(valid_points);
                app.Z_points = app.Y_points;
                app.Z_points(:) = 0;
            else
                scatter(app.X_points,app.Y_points,'MarkerFaceColor','b','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','o')

                for i = 1:length(app.X_points)
                    for j = 1:length(app.X_locations)
                        [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                    end
                end
                n = app.N-length(app.X_points);
                [x,y] = ginput(n);
                valid_points = x >= app.xmin & x <= app.xmax & y >= app.ymin & y <= app.ymax;
                app.X_points = [app.X_points, x(valid_points)'];
                app.Y_points = [app.Y_points, y(valid_points)'];
                app.Z_points = app.Y_points;
                app.Z_points(:) = 0;
            end

            close all

            h=figure;
            hold on
            grid on
            ax=gca;
            ax.Color = [.5,.5,.5];
            h.Position = [0,0,1500,1500];


            count = 0;
            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    count = count+1;
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                    lines(count,:) = [app.X_points(i),app.Y_points(i),app.Z_points(i),app.X_locations(j),app.Y_locations(j),app.Z_locations(j)];
                end
            end

            count2 = 0;
            for i = 1:count
                for j = 1:count
                    if i ~= j
                        PA = [lines(i,(1:3));lines(j,(1:3))];
                        PB = [lines(i,(4:6));lines(j,(4:6))];
                        
                        if (PA(1,1) ~= PA(2,1) | PA(1,2) ~= PA(2,2)) & (PB(1,1) ~= PB(2,1) | PB(1,2) ~= PB(2,2))
                            [P_int] = lineIntersect3D(PA,PB);

                            angle_line1 = atan2d((PA(1,2)-PB(1,2)),(PA(1,1)-PB(1,1)));
                            angle_line2 = atan2d((PA(2,2)-PB(2,2)),(PA(2,1)-PB(2,1)));

                            angle_point1 = atan2d((P_int(1,2)-PB(1,2)),(P_int(1,1)-PB(1,1)));
                            angle_point2 = atan2d((P_int(1,2)-PB(2,2)),(P_int(1,1)-PB(2,1)));


                            angle_delta1 = abs(angle_line1-angle_point1);
                            angle_delta2 = abs(angle_line2-angle_point2);

                            if angle_delta1<10 & angle_delta2 < 10
                                X_same = app.X_points - P_int(1);
                                Y_same = app.Y_points - P_int(2);
                                X_same = abs(X_same) < .001;
                                Y_same = abs(Y_same) < .001;
                                Same1 = X_same & Y_same;

                                X_same2 = app.X_locations - P_int(1);
                                Y_same2 = app.Y_locations - P_int(2);
                                X_same2 = abs(X_same2) < .001;
                                Y_same2 = abs(Y_same2) < .001;
                                Same2 = X_same2 & Y_same2;

                                if P_int(1) >= app.xmin & P_int(1) <= app.xmax & P_int(2) >= app.ymin & P_int(2) <= app.ymax & sum(Same1) == 0 & sum(Same2)==0
                                    count2 = count2+1;
                                    P_final(count2,:) = P_int(1,:);
                                end
                            end
                        end
                    end
                end
            end


            x_bins = app.xmin:.2:app.xmax+.2;
            y_bins = app.ymin:.2:app.ymax+.2;

            for i = 1:length(x_bins)-1
                for j = 1:length(y_bins)-1
                    x_take(i) = (x_bins(i)+x_bins(i+1))/2;
                    y_take(j) = (y_bins(j)+y_bins(j+1))/2;
                    x_take(i) = x_bins(i);
                    y_take(j) = y_bins(j);
                    indx1 = P_final(:,1) >= x_bins(i) & P_final(:,1) < x_bins(i+1);
                    indx2 = P_final(:,2) >= y_bins(j) & P_final(:,2) < y_bins(j+1);
                    indx_total = indx1 & indx2;
                    C_contour(i,j) = sum(indx_total);
                end
            end

            C_contour(C_contour>=20) = 20;
            S = pcolor(x_take,y_take,C_contour')
            S.FaceAlpha = .5;
            colormap('jet')
            clim([0 20])

            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                end
            end

            scatter(P_final(:,1),P_final(:,2),'MarkerFaceColor','c','MarkerEdgeColor','k','linewidth',2,'SizeData',50,'Marker','s')
            line([app.xmin,app.xmin],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmax,app.xmax],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymin,app.ymin],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymax,app.ymax],'linewidth',2,'color','k')
            xlim([app.xmin-1,app.xmax+1])
            ylim([app.ymin-1,app.ymax+1])

            [A,B] = size(C_contour);
            C_Area = (A-1)*(B-1);
            indx_good = C_contour(C_contour>=1);
            num_good = length(indx_good);
            text_str = ['Crossings : ' num2str(count2/2)  ' , Area : ' num2str(round((num_good/C_Area)*100),'%2.0f') '% , Score : ' num2str(round((count2/2)*(num_good/C_Area)))];
            text(0,10.5,text_str,'FontSize',40,'FontWeight','bold')

            scatter(app.X_locations,app.Y_locations,'MarkerFaceColor','r','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','d')
            scatter(app.X_points,app.Y_points,'MarkerFaceColor','b','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','o')


        end

        % Button pushed function: MovePointsButton
        function MovePointsButtonPushed(app, event)
                
            h=figure;
            hold on
            grid on
            ax=gca;
            ax.Color = [.5,.5,.5];
            h.Position = [0,0,1500,1500];
            scatter(app.X_locations,app.Y_locations,'MarkerFaceColor','r','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','d')
            scatter(app.X_points,app.Y_points,'MarkerFaceColor','b','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','o')

            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                end
            end

            line([app.xmin,app.xmin],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmax,app.xmax],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymin,app.ymin],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymax,app.ymax],'linewidth',2,'color','k')
            xlim([app.xmin-1,app.xmax+1])
            ylim([app.ymin-1,app.ymax+1])

            scatter(app.X_points,app.Y_points,'Marker','o','SizeData',300,'MarkerEdgeColor','k','markerfacecolor','y','hittest','on','buttondownfcn',@clickmarker)

            input('Press enter when done moving')
            app.X_points= h.Children.Children(1).XData;
            app.Y_points = h.Children.Children(1).YData;

            close all

            h=figure;
            hold on
            grid on
            ax=gca;
            ax.Color = [.5,.5,.5];
            h.Position = [0,0,1500,1500];


            count = 0;
            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    count = count+1;
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                    lines(count,:) = [app.X_points(i),app.Y_points(i),app.Z_points(i),app.X_locations(j),app.Y_locations(j),app.Z_locations(j)];
                end
            end

            count2 = 0;
            for i = 1:count
                for j = 1:count
                    if i ~= j
                        PA = [lines(i,(1:3));lines(j,(1:3))];
                        PB = [lines(i,(4:6));lines(j,(4:6))];
                        
                        if (PA(1,1) ~= PA(2,1) | PA(1,2) ~= PA(2,2)) & (PB(1,1) ~= PB(2,1) | PB(1,2) ~= PB(2,2))
                            [P_int] = lineIntersect3D(PA,PB);

                            angle_line1 = atan2d((PA(1,2)-PB(1,2)),(PA(1,1)-PB(1,1)));
                            angle_line2 = atan2d((PA(2,2)-PB(2,2)),(PA(2,1)-PB(2,1)));

                            angle_point1 = atan2d((P_int(1,2)-PB(1,2)),(P_int(1,1)-PB(1,1)));
                            angle_point2 = atan2d((P_int(1,2)-PB(2,2)),(P_int(1,1)-PB(2,1)));


                            angle_delta1 = abs(angle_line1-angle_point1);
                            angle_delta2 = abs(angle_line2-angle_point2);

                            if angle_delta1<10 & angle_delta2 < 10
                                X_same = app.X_points - P_int(1);
                                Y_same = app.Y_points - P_int(2);
                                X_same = abs(X_same) < .001;
                                Y_same = abs(Y_same) < .001;
                                Same1 = X_same & Y_same;

                                X_same2 = app.X_locations - P_int(1);
                                Y_same2 = app.Y_locations - P_int(2);
                                X_same2 = abs(X_same2) < .001;
                                Y_same2 = abs(Y_same2) < .001;
                                Same2 = X_same2 & Y_same2;

                                if P_int(1) >= app.xmin & P_int(1) <= app.xmax & P_int(2) >= app.ymin & P_int(2) <= app.ymax & sum(Same1) == 0 & sum(Same2)==0
                                    count2 = count2+1;
                                    P_final(count2,:) = P_int(1,:);
                                end
                            end
                        end
                    end
                end
            end

            x_bins = app.xmin:.2:app.xmax+.2;
            y_bins = app.ymin:.2:app.ymax+.2;

            for i = 1:length(x_bins)-1
                for j = 1:length(y_bins)-1
                    x_take(i) = (x_bins(i)+x_bins(i+1))/2;
                    y_take(j) = (y_bins(j)+y_bins(j+1))/2;
                    x_take(i) = x_bins(i);
                    y_take(j) = y_bins(j);
                    indx1 = P_final(:,1) >= x_bins(i) & P_final(:,1) < x_bins(i+1);
                    indx2 = P_final(:,2) >= y_bins(j) & P_final(:,2) < y_bins(j+1);
                    indx_total = indx1 & indx2;
                    C_contour(i,j) = sum(indx_total);
                end
            end

            C_contour(C_contour>=20) = 20;
            S = pcolor(x_take,y_take,C_contour')
            S.FaceAlpha = .5;
            colormap('jet')
            clim([0 20])

            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                end
            end

            scatter(P_final(:,1),P_final(:,2),'MarkerFaceColor','c','MarkerEdgeColor','k','linewidth',2,'SizeData',50,'Marker','s')
            line([app.xmin,app.xmin],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmax,app.xmax],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymin,app.ymin],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymax,app.ymax],'linewidth',2,'color','k')
            xlim([app.xmin-1,app.xmax+1])
            ylim([app.ymin-1,app.ymax+1])

            [A,B] = size(C_contour);
            C_Area = (A-1)*(B-1);
            indx_good = C_contour(C_contour>=1);
            num_good = length(indx_good);
            text_str = ['Crossings : ' num2str(count2/2)  ' , Area : ' num2str(round((num_good/C_Area)*100),'%2.0f') '% , Score : ' num2str(round((count2/2)*(num_good/C_Area)),'%4.0f')];
            text(0,10.5,text_str,'FontSize',40,'FontWeight','bold')

            scatter(app.X_locations,app.Y_locations,'MarkerFaceColor','r','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','d')
            scatter(app.X_points,app.Y_points,'MarkerFaceColor','b','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','o')
        end

        % Button pushed function: ParticleStormButton
        function ParticleStormButtonPushed(app, event)
            app.LevelSelector8EditFieldValueChanged(event)
            num_particles = app.ParticleSizeEditField.Value;
            data = load("scenario_info.mat");
            info = data.scenario_info;
            num_vars = info.N * 2;
            % Set every app property based on info field names
            prop_names = fieldnames(info);
            num_props = length(prop_names);
            for ii = 1:num_props
                name = prop_names{ii};
                app.(name) = info.(name);
            end

            % Set up optimization options
            warning('off', 'all')
            parfevalOnAll(@warning,0,'off','all')
            options = optimoptions('particleswarm', 'Display', 'iter', 'SwarmSize', num_particles,'UseParallel',true,'OutputFcn',@outfun);
            lower_bound = zeros(app.N*2, 1);
            upper_bound = 10 * ones(app.N*2, 1);

            % History container
            history = [];
            function stop = outfun(x,~)
                stop = false;
                history = [history;x];
            end
            
            % Run PSO
            final_score = particleswarm(@app.cross_calc2, num_vars, lower_bound, upper_bound, options);


            x_tot = [];
            y_tot = [];
            z_tot = [];
            x_totb = [];
            y_totb = [];
            z_totb = [];
            for j = 1:length(history)
                bestx = history(j).bestx;
                s_now = history(j).swarm;
                x_totb = [x_totb;bestx(1:(num_vars/2))'];
                y_totb = [y_totb;bestx((num_vars/2)+1:end)'];
                z_totb_temp = bestx(1:(num_vars/2));
                z_totb_temp(:) = history(j).bestfval;
                z_totb = [z_totb;z_totb_temp'];
                [A, ~] = size(s_now)
                for k = 1:A
                    x_now = history(j).swarm(k,1:(num_vars/2));
                    y_now = history(j).swarm(k,(num_vars/2)+1:end);
                    z_now = x_now;
                    z_now(:) = history(j).swarmfvals(k);

                    x_tot = [x_tot; x_now'];
                    y_tot = [y_tot; y_now'];
                    z_tot = [z_tot; z_now'];
                end

            end


            Data.x_tot = x_tot;
            Data.y_tot = y_tot;
            Data.z_tot = z_tot;
            Data.x_totb = x_totb;
            Data.y_totb = y_totb;
            Data.z_totb = z_totb;


            app.X_points = final_score(1:(num_vars/2));
            app.Y_points = final_score((num_vars/2)+1:end);

            Data.X_points = app.X_points;
            Data.Y_points = app.Y_points;
            Data.X_loc = app.X_locations;
            Data.Y_loc = app.Y_locations;
            cost = app.cross_calc2(final_score);
            cost = -1*cost;
            title_str = ['Level_' num2str(app.LevelSelector8EditField.Value) '_Score_' num2str(cost)];
            save([title_str '.mat'],"Data")

            h=figure;
            hold on
            grid on
            ax=gca;
            ax.Color = [.5,.5,.5];
            h.Position = [0,0,1500,1500];


            app.Z_points = app.X_points;
            app.Z_points(:) = 0;
            app.Z_locations = app.X_locations;
            app.Z_locations(:) = 0;

            count = 0;
            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    count = count+1;
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                    lines(count,:) = [app.X_points(i),app.Y_points(i),app.Z_points(i),app.X_locations(j),app.Y_locations(j),app.Z_locations(j)];
                end
            end

            count2 = 0;
            for i = 1:count
                for j = 1:count
                    if i ~= j
                        PA = [lines(i,(1:3));lines(j,(1:3))];
                        PB = [lines(i,(4:6));lines(j,(4:6))];

                        if (PA(1,1) ~= PA(2,1) | PA(1,2) ~= PA(2,2)) & (PB(1,1) ~= PB(2,1) | PB(1,2) ~= PB(2,2))
                            [P_int] = lineIntersect3D(PA,PB);

                            angle_line1 = atan2d((PA(1,2)-PB(1,2)),(PA(1,1)-PB(1,1)));
                            angle_line2 = atan2d((PA(2,2)-PB(2,2)),(PA(2,1)-PB(2,1)));

                            angle_point1 = atan2d((P_int(1,2)-PB(1,2)),(P_int(1,1)-PB(1,1)));
                            angle_point2 = atan2d((P_int(1,2)-PB(2,2)),(P_int(1,1)-PB(2,1)));


                            angle_delta1 = abs(angle_line1-angle_point1);
                            angle_delta2 = abs(angle_line2-angle_point2);

                            if angle_delta1<10 & angle_delta2 < 10
                                X_same = app.X_points - P_int(1);
                                Y_same = app.Y_points - P_int(2);
                                X_same = abs(X_same) < .001;
                                Y_same = abs(Y_same) < .001;
                                Same1 = X_same & Y_same;

                                X_same2 = app.X_locations - P_int(1);
                                Y_same2 = app.Y_locations - P_int(2);
                                X_same2 = abs(X_same2) < .001;
                                Y_same2 = abs(Y_same2) < .001;
                                Same2 = X_same2 & Y_same2;

                                if P_int(1) >= app.xmin & P_int(1) <= app.xmax & P_int(2) >= app.ymin & P_int(2) <= app.ymax & sum(Same1) == 0 & sum(Same2)==0
                                    count2 = count2+1;
                                    P_final(count2,:) = P_int(1,:);
                                end
                            end
                        end
                    end
                end
            end

            x_bins = app.xmin:.2:app.xmax+.2;
            y_bins = app.ymin:.2:app.ymax+.2;

            for i = 1:length(x_bins)-1
                for j = 1:length(y_bins)-1
                    x_take(i) = (x_bins(i)+x_bins(i+1))/2;
                    y_take(j) = (y_bins(j)+y_bins(j+1))/2;
                    x_take(i) = x_bins(i);
                    y_take(j) = y_bins(j);
                    indx1 = P_final(:,1) >= x_bins(i) & P_final(:,1) < x_bins(i+1);
                    indx2 = P_final(:,2) >= y_bins(j) & P_final(:,2) < y_bins(j+1);
                    indx_total = indx1 & indx2;
                    C_contour(i,j) = sum(indx_total);
                end
            end

            C_contour(C_contour>=20) = 20;
            S = pcolor(x_take,y_take,C_contour')
            S.FaceAlpha = .5;
            colormap('jet')
            clim([0 20])

            for i = 1:length(app.X_points)
                for j = 1:length(app.X_locations)
                    [line_end] = draw_line(ax,app.X_locations(j),app.Y_locations(j),0,app.X_points(i),app.Y_points(i),0,app.R);
                end
            end

            scatter(P_final(:,1),P_final(:,2),'MarkerFaceColor','c','MarkerEdgeColor','k','linewidth',2,'SizeData',50,'Marker','s')
            line([app.xmin,app.xmin],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmax,app.xmax],[app.ymin,app.ymax],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymin,app.ymin],'linewidth',2,'color','k')
            line([app.xmin,app.xmax],[app.ymax,app.ymax],'linewidth',2,'color','k')
            xlim([app.xmin-1,app.xmax+1])
            ylim([app.ymin-1,app.ymax+1])

            [A,B] = size(C_contour);
            C_Area = (A-1)*(B-1);
            indx_good = C_contour(C_contour>=1);
            num_good = length(indx_good);
            text_str = ['Crossings : ' num2str(count2/2)  ' , Area : ' num2str(round((num_good/C_Area)*100),'%2.0f') '% , Score : ' num2str(round((count2/2)*(num_good/C_Area)),'%4.0f')];
            text(0,10.5,text_str,'FontSize',40,'FontWeight','bold')

            scatter(app.X_locations,app.Y_locations,'MarkerFaceColor','r','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','d')
            scatter(app.X_points,app.Y_points,'MarkerFaceColor','b','MarkerEdgeColor','k','linewidth',2,'SizeData',300,'Marker','o')

            savefig([title_str '.fig'])
            close(h)
        end

        % Button pushed function: Storm10TimesButton
        function Storm10TimesButtonPushed(app, event)
            for i = 1:10
                ParticleStormButtonPushed(app,"ButtonPushed")
            end
        end

        % Value changed function: LevelSelector8EditField
        function LevelSelector8EditFieldValueChanged(app, event)
            value = app.LevelSelector8EditField.Value;
            scenario_info = app.get_scenario_info(value);
            save("scenario_info.mat",'scenario_info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BasicGame4PoorsPanel = uipanel(app.UIFigure);
            app.BasicGame4PoorsPanel.Title = 'Basic Game (4Poors)';
            app.DropPointsButton = uibutton(app.BasicGame4PoorsPanel, 'push');
            app.DropPointsButton.ButtonPushedFcn = createCallbackFcn(app, @DropPointsButtonPushed, true);
            app.DropPointsButton.Text = 'Drop Points';
            app.MovePointsButton = uibutton(app.BasicGame4PoorsPanel, 'push');
            app.MovePointsButton.ButtonPushedFcn = createCallbackFcn(app, @MovePointsButtonPushed, true);
            app.MovePointsButton.Text = 'Move Points';
            app.LevelSelector15Label = uilabel(app.BasicGame4PoorsPanel);
            app.LevelSelector15Label.Text = 'Level Selector (8)';
            app.LevelSelector8EditField = uieditfield(app.BasicGame4PoorsPanel, 'numeric');
            app.LevelSelector8EditField.ValueChangedFcn = createCallbackFcn(app, @LevelSelector8EditFieldValueChanged, true);
            app.LevelSelector8EditField.Value = 8;
            app.RichPeopleVersionPanel = uipanel(app.UIFigure);
            app.RichPeopleVersionPanel.Title = 'Rich People Version';
            app.ParticleStormButton = uibutton(app.RichPeopleVersionPanel, 'push');
            app.ParticleStormButton.ButtonPushedFcn = createCallbackFcn(app, @ParticleStormButtonPushed, true);
            app.ParticleStormButton.Text = 'Particle Storm';
            app.ParticleSizeEditFieldLabel = uilabel(app.RichPeopleVersionPanel);
            app.ParticleSizeEditFieldLabel.Text = 'Particle Size';
            app.ParticleSizeEditField = uieditfield(app.RichPeopleVersionPanel, 'numeric');
            app.ParticleSizeEditField.Value = 10;
            app.Storm10TimesButton = uibutton(app.RichPeopleVersionPanel, 'push');
            app.Storm10TimesButton.ButtonPushedFcn = createCallbackFcn(app, @Storm10TimesButtonPushed, true);
            app.Storm10TimesButton.Text = 'Storm 10 Times';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = angle_contest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end